classdef Proiect_Cebisev < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Calculeaza            matlab.ui.control.Button
        sol                   matlab.ui.control.EditField
        AproximareaintegraleiesteEditFieldLabel  matlab.ui.control.Label
        Numaruldenoduri       matlab.ui.control.EditField
        NumaruldenoduriLabel  matlab.ui.control.Label
        Functiapecareselucreazaeste13xLabel  matlab.ui.control.Label
        subtitlu              matlab.ui.control.Label
        titlu                 matlab.ui.control.Label
    end

    
    properties (Access = private)
         n=[];I=[];f=@f;nrnod=[];
         
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Numaruldenoduri.Value='4';
            app.nrnod=str2num(app.Numaruldenoduri.Value);
            app.I=cebisev(app.f,app.nrnod);
            app.sol.Value=num2str(app.I,12);
        end

        % Button pushed function: Calculeaza
        function CalculeazaButtonPushed(app, event)
            app.n=str2num(app.Numaruldenoduri.Value);
            app.I=cebisev(app.f,app.n);
            app.sol.Value=num2str(app.I,12);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'calculatoricon.jpg';
            app.titlu = uilabel(app.UIFigure);
            app.titlu.Text = 'Proiect formula de cuadratura de tip Cebisev';
            app.subtitlu = uilabel(app.UIFigure);
            app.subtitlu.Text = 'In cazul studiat ponderea=1';
            app.Functiapecareselucreazaeste13xLabel = uilabel(app.UIFigure);
            app.Functiapecareselucreazaeste13xLabel.Text = {'Functia pe care se lucreaza este:'; '1./(3+x)'};
            app.NumaruldenoduriLabel = uilabel(app.UIFigure);
            app.NumaruldenoduriLabel.Text = 'Numarul de noduri:';
            app.Numaruldenoduri = uieditfield(app.UIFigure, 'text');
            app.Numaruldenoduri.Placeholder = '0';
            app.AproximareaintegraleiesteEditFieldLabel = uilabel(app.UIFigure);
            app.AproximareaintegraleiesteEditFieldLabel.Text = 'Aproximarea integralei este:';
            app.sol = uieditfield(app.UIFigure, 'text');
            app.sol.Placeholder = '0';
            app.Calculeaza = uibutton(app.UIFigure, 'push');
            app.Calculeaza.ButtonPushedFcn = createCallbackFcn(app, @CalculeazaButtonPushed, true);
            app.Calculeaza.Icon = 'calculatoricon.jpg';
            app.Calculeaza.Text = 'Calculeaza';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Proiect_Cebisev

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end